classdef Smart_Specto3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SMARTTestBedUIFigure            matlab.ui.Figure
        RealTimeConcentrationPanel      matlab.ui.container.Panel
        StatusLamp_3                    matlab.ui.control.Lamp
        StatusLamp_2Label_2             matlab.ui.control.Label
        StartCalculationSwitch          matlab.ui.control.Switch
        StartCalculationSwitchLabel     matlab.ui.control.Label
        AxConcMB                        matlab.ui.control.UIAxes
        AxConcTY                        matlab.ui.control.UIAxes
        TYCalibButton                   matlab.ui.control.Button
        ConnArduino                     matlab.ui.control.Button
        SalineFlushButton               matlab.ui.control.Button
        TGFlushButton                   matlab.ui.control.Button
        ERIFlushButton                  matlab.ui.control.Button
        ScalePanel                      matlab.ui.container.Panel
        DropDown_3                      matlab.ui.control.DropDown
        CheckBoxScale                   matlab.ui.control.CheckBox
        StatusLampScale                 matlab.ui.control.Lamp
        StatusLamp_2Label               matlab.ui.control.Label
        COMScaleEdit                    matlab.ui.control.NumericEditField
        COMEditFieldLabel_3             matlab.ui.control.Label
        AxesScale                       matlab.ui.control.UIAxes
        FileLoadButton                  matlab.ui.control.Button
        FileEditField                   matlab.ui.control.EditField
        FileEditFieldLabel              matlab.ui.control.Label
        DataLoggerLamp                  matlab.ui.control.Lamp
        DataLoggerSwitch                matlab.ui.control.Switch
        DataLoggerLabel                 matlab.ui.control.Label
        EndExperimentButton             matlab.ui.control.Button
        BeginExperimentButton           matlab.ui.control.Button
        SalineCalibButton               matlab.ui.control.Button
        ERICalibButton                  matlab.ui.control.Button
        ExperimentStepsLabel            matlab.ui.control.Label
        SMARTTestBedLabel               matlab.ui.control.Label
        PumpsPanel                      matlab.ui.container.Panel
        testbuttonButton                matlab.ui.control.Button
        UpdateFlowButton                matlab.ui.control.Button
        Actual3Edit                     matlab.ui.control.NumericEditField
        EditFieldLabel_3                matlab.ui.control.Label
        Actual2Edit                     matlab.ui.control.NumericEditField
        EditFieldLabel_2                matlab.ui.control.Label
        Actual1Edit                     matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        ActualLabel                     matlab.ui.control.Label
        ManualSwitch                    matlab.ui.control.ToggleSwitch
        ManualSwitchLabel               matlab.ui.control.Label
        DropDown_2                      matlab.ui.control.DropDown
        DropDown                        matlab.ui.control.DropDown
        MaxPump3Edit                    matlab.ui.control.NumericEditField
        MaxLabel_3                      matlab.ui.control.Label
        SteadyPump2mlhr_2               matlab.ui.control.Label
        SteadyPump3Edit                 matlab.ui.control.NumericEditField
        SteadyLabel_4                   matlab.ui.control.Label
        COMEditFieldLabel_4             matlab.ui.control.Label
        MaxPump3mlhr                    matlab.ui.control.Label
        COMPump3Edit                    matlab.ui.control.NumericEditField
        Pump3CheckBox                   matlab.ui.control.CheckBox
        MaxPump2mlhr                    matlab.ui.control.Label
        MaxPump1mlhr                    matlab.ui.control.Label
        SteadyPump2mlhr                 matlab.ui.control.Label
        SteadyPump1mlhr                 matlab.ui.control.Label
        MaxPump2Edit                    matlab.ui.control.NumericEditField
        MaxLabel_2                      matlab.ui.control.Label
        SteadyPump2Edit                 matlab.ui.control.NumericEditField
        SteadyLabel_2                   matlab.ui.control.Label
        COMPump2Edit                    matlab.ui.control.NumericEditField
        COMEditFieldLabel_2             matlab.ui.control.Label
        Pump2CheckBox                   matlab.ui.control.CheckBox
        MaxPump1Edit                    matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        SteadyPump1Edit                 matlab.ui.control.NumericEditField
        SteadyLabel                     matlab.ui.control.Label
        COMPump1Edit                    matlab.ui.control.NumericEditField
        COMEditFieldLabel               matlab.ui.control.Label
        Pump1CheckBox                   matlab.ui.control.CheckBox
        SpectrophometerPanel            matlab.ui.container.Panel
        AcquisitionIntervalEdit         matlab.ui.control.NumericEditField
        AcquisitionIntervalsLabel       matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        StartAcquisitionSwitch          matlab.ui.control.Switch
        StartAcquisitionSwitchLabel     matlab.ui.control.Label
        BinHalfWidth2Edit               matlab.ui.control.NumericEditField
        BinHalfWidthEditField_2Label    matlab.ui.control.Label
        BinHalfWidth1Edit               matlab.ui.control.NumericEditField
        BinHalfWidthEditFieldLabel      matlab.ui.control.Label
        BinWavelength2nmEdit            matlab.ui.control.NumericEditField
        BinWavelength2nmEditFieldLabel  matlab.ui.control.Label
        BinWavelength1nmEdit            matlab.ui.control.NumericEditField
        BinWavelength1nmEditFieldLabel  matlab.ui.control.Label
        IntegrationTimesEdit            matlab.ui.control.NumericEditField
        IntegrationTimesEditFieldLabel  matlab.ui.control.Label
        AxInt424                        matlab.ui.control.UIAxes
        AxInt631                        matlab.ui.control.UIAxes
        AxesSpec                        matlab.ui.control.UIAxes
    end

    % Smart_Specto3 is a MATLAB application for controlling experimental
    % apparatus including pumps and a spectrophotometer. It allows for
    % real-time data acquisition, control of fluidic pumps, and analysis
    % of the resulting data to compute concentrations based on calibration
    % curves.
    properties (Access = public)
        specparams % Params of the spectrophotometer 
        TS %Instance of ThorSpectrophotometer class
        KD %Instances of the KDPump class
        AP %Instance of ArduinoPump class
        ScaleObj % Instance of the Ainsworth class

        wldata;
        specdata;
        
        binWavelength = zeros(2,108000);  % Store data from bin wavelenth
        scaleData = zeros(1,108000); %Store mass data
        timeVec = zeros(1,108000); %Store acquisition time
        
        strtIdx  = zeros(1,2); % Wavelength idx locations
        endIdx  = zeros(1,2); % Wavelength idx locations
        
        % CSV File
        filename = []; %Csv file name for storing data
        path = []; % Path for storing csv file
        
        % Baseline plots
        xValDrug = [300, 800];
        yValDrug = [nan, nan];
        xValTY = [300, 800];
        yValTY = [nan, nan];
        xValSaline = [300, 800];
        yValSaline = [nan, nan];
        
        %Max acqusition time
        tMax = 3600*3; % Current experiments are capped at 3hrs once the experiment is started
        expStartFlag = 0; 
        scaleStartFlag = 0;
        fileIdx = 1;
        
        
        timer % Timer per azioni sequenziali
        timeElapsed % Tempo trascorso per il timer
    end

    methods (Access = public)
    
        function normConc = getNormConc(app, binIntensity)
             % Calculates the normalized concentration based on the calibration curve.
             % Assumes max concentration is 100 ug/ml.
             % This can be improved by avoiding Cp calculation here.
            
            if isnan(app.yValSaline(1))
                normConc = binIntensity;
                return
            end
            
            scaledBinIntensity = (binIntensity - app.yValDrug(1))/(app.yValSaline(1) - app.yValDrug(1)); 
            %MB
            Cp = 100*(app.SteadyPump2Edit.Value)/(app.SteadyPump1Edit.Value + app.SteadyPump2Edit.Value);
            normConc = (-log(scaledBinIntensity)/0.04606)/Cp;
            
            
        end
    
        function [CTar, CMeth] = solveConcentrations(app, I631mix, I424mix)
            % Define the initial guess for CTar and CMeth
            x0 = [1, 2];
            
            % Correctly specify 'fsolve' as the solver in optimoptions
            options = optimoptions('fsolve', 'Display', 'none'); % Suppress fsolve output
            
            % Call fsolve to solve the system of equations
            [x, ~, exitflag] = fsolve(@(x)app.myEquations(x, I631mix, I424mix), x0, options);
            
            % Check if fsolve succeeded
            if exitflag <= 0
                warning('The fsolve solver did not converge.');
                CTar = NaN;
                CMeth = NaN;
            else
                CTar = x(1);
                CMeth = x(2);
            end
           end
        
         function F = myEquations(app, x, I631mix, I424mix)
            % Defines the system of equations to be solved by fsolve.
            % This represents the calibration equations for each component.
            CTar = x(1);
            CMeth = x(2);

            % define the function to solve
            % Calibration 2024/03
            I630T = 5.936e-06 *CTar + 0.9994;
            I430T = 1.027*exp(-0.05342*CTar);
            I630M = 1.015*exp(-0.1563*CMeth);
            I430M = -0.006415*CMeth + 1.001;

             F(1) = I424mix - I630T - I430T + 1;
             F(2) = I631mix - I630M - I430M + 1;
        
        end
        
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create a instance of the ThorSpectrophotometer class
            addpath('./HardwareControlModule/');
            app.TS = ThorSpectrophotometer();
            app.TS.inttime = app.IntegrationTimesEdit.Value;
            
            % Change status light
            if app.TS.initError
            app.StatusLamp.Color = [1 0 0];
            else
            app.StatusLamp.Color = [1 0.5 0];
            end
            
            % Get Start and End Idx
            [app.wldata,app.specdata] = app.TS.getSpectrum;         
            [~, app.strtIdx(1)] =  min(abs(app.wldata.value - (app.BinWavelength1nmEdit.Value - app.BinHalfWidth1Edit.Value)));
            [~, app.endIdx(1)] = min(abs(app.wldata.value - (app.BinWavelength1nmEdit.Value + app.BinHalfWidth1Edit.Value)));
            [~, app.strtIdx(2)] =  min(abs(app.wldata.value - (app.BinWavelength2nmEdit.Value - app.BinHalfWidth2Edit.Value)));
            [~, app.endIdx(2)] = min(abs(app.wldata.value - (app.BinWavelength2nmEdit.Value + app.BinHalfWidth2Edit.Value)));
            
            % Axes prep
            hold(app.AxInt631, 'on');
            hold(app.AxInt424, 'on');
            hold(app.AxConcMB, 'on');
            hold(app.AxConcTY, 'on');
            hold(app.AxesSpec, 'on');
            hold(app.AxesScale, 'on')
            
            % Create instance of the scale class
            if app.CheckBoxScale.Value
            app.ScaleObj = Ainsworth(strcat('COM',num2str(app.COMScaleEdit.Value)));
            end


        end

        % Value changed function: BinWavelength1nmEdit
        function BinWavelength1nmEditValueChanged(app, event)
            app.specparams.BinWavelength1 = app.BinWavelength1nmEdit.Value;         
        end

        % Value changed function: BinWavelength2nmEdit
        function BinWavelength2nmEditValueChanged(app, event)
            app.params.BinWavelength2 = app.BinWavelength2nmEdit.Value;         
        end

        % Value changed function: BinHalfWidth1Edit
        function BinHalfWidth1EditValueChanged(app, event)
            app.params.BinWavelength1 = app.BinHalfWidth1Edit.Value;          
        end

        % Value changed function: BinHalfWidth2Edit
        function BinHalfWidth2EditValueChanged(app, event)
            app.params.BinWavelength2 = app.BinHalfWidth2Edit.Value;        
        end

        % Close request function: SMARTTestBedUIFigure
        function SMARTTestBedUIFigureCloseRequest(app, event)
            app.TS.delete();
            delete(app)  
        end

        % Value changed function: IntegrationTimesEdit
        function IntegrationTimesEditValueChanged(app, event)
            app.TS.inttime = app.IntegrationTimesEdit.Value;
        end

        % Value changed function: StartAcquisitionSwitch
        function StartAcquisitionSwitchValueChanged(app, event)
          
            if strcmp(app.StartAcquisitionSwitch.Value, 'Off')
                app.StatusLamp.Color = [1 0.5 0];
                app.StatusLampScale.Color = [1 0.5 0];
                return
            end
            
            
            % Iteratively update the AxesSpec
            app.StatusLamp.Color = [0 1 0];
            app.StatusLampScale.Color = [0 1 0];
            app.ERICalibButton.Enable =  1;
            
            tic
            while strcmp(app.StartAcquisitionSwitch.Value, 'On')
             % Acquire and calculate   
             [app.wldata,app.specdata] = app.TS.getSpectrum;
             binData = app.TS.getBinnedDataHE(app.specdata.value, app.strtIdx, app.endIdx);
             %Solve the concentration for plotting
             
                   
%             I631mix= binIntensity(2)
%             I424mix =binIntensity(1)
             I631mix = binData(2); % Assuming you obtain these from the app UI
             I424mix = binData(1);
    

             % Display data
             cla(app.AxesSpec);
             plot(app.AxesSpec, app.wldata.value,app.specdata.value, 'LineWidth', 1.5);
             plot(app.AxesSpec, app.xValDrug, [app.yValDrug(2), app.yValDrug(2)], '--k', 'LineWidth', 1.5);
             plot(app.AxesSpec, app.xValTY, [app.yValTY(1), app.yValTY(1)], '--k', 'LineWidth', 1.5);
             plot(app.AxesSpec, app.xValSaline, [app.yValSaline(1), app.yValSaline(1)], '--k', 'LineWidth', 1.5);
             
             plot(app.AxInt631, toc/60,  app.getNormConc(binData(2)), '.b', 'MarkerSize', 10);
             plot(app.AxInt424, toc/60,  app.getNormConc(binData(1)), '.b', 'MarkerSize', 10);
             
                         
             if strcmp(app.StartCalculationSwitch.Value, 'On')
             [CTar, CMeth] = app.solveConcentrations(I631mix, I424mix);
             plot(app.AxConcMB, toc/60,  CMeth/25, '.b', 'MarkerSize', 10);
             plot(app.AxConcTY, toc/60,  CTar/100, '.b', 'MarkerSize', 10);
                 
            end


             %Display scale
             if app.CheckBoxScale.Value
             currMass = app.ScaleObj.mass;
             
             plot(app.AxesScale, toc/60, currMass, '.b', 'MarkerSize', 10);

             
             end

             % Log data if the experiment has started
             if app.expStartFlag
                 app.binWavelength(:,app.fileIdx) = binData;
                 app.timeVec(app.fileIdx) = toc;
                 if app.CheckBoxScale.Value
                 app.scaleData(app.fileIdx) = currMass;
                 end
                 app.fileIdx = app.fileIdx+1;
                 if app.tMax-toc < 0
                     % Call the experiment end triggers
                     break
                 end
                 pause((app.fileIdx-1)*app.AcquisitionIntervalEdit.Value - toc);  
             else
                 pause(app.AcquisitionIntervalEdit.Value);
             end
             
             
            end
            
        end

        % Value changed function: AcquisitionIntervalEdit
        function AcquisitionIntervalEditValueChanged(app, event)
            if app.AcquisitionIntervalEdit.Value<0.1
                app.AcquisitionIntervalEdit.Value = 0.1;
            end
        end

        % Value changed function: DataLoggerSwitch
        function DataLoggerSwitchValueChanged(app, event)
             if strcmp(app.DataLoggerSwitch.Value, 'On')
                app.DataLoggerLamp.Color = [1 0.5 0];
             else
                app.DataLoggerLamp.Color = [0.94 0.94 0.94]; 
             end
            
        end

        % Button pushed function: FileLoadButton
        function FileLoadButtonPushed(app, event)
            [app.filename,app.path] = uiputfile({'*.csv'});
            app.FileEditField.Value = app.filename;
            % Bring UI to the front
            figure(app.SMARTTestBedUIFigure);
            
        end

        % Button pushed function: ERICalibButton
        function ERICalibButtonPushed(app, event)
            %maxVal = max(app.specdata.value);
            binData = app.TS.getBinnedDataHE(app.specdata.value, app.strtIdx, app.endIdx);
            app.yValDrug = [binData(1), binData(2)];
            app.ERICalibButton.Enable = 0;
            app.TYCalibButton.Enable = 1;
              try
                  app.KD.Pump1.stop();
                  app.KD.Pump2.stop();
                  app.KD.Pump3.stop();
              catch
              end
        end

        % Button pushed function: SalineCalibButton
        function SalineCalibButtonPushed(app, event)
           %maxVal = max(app.specdata.value);
            binData = app.TS.getBinnedDataHE(app.specdata.value, app.strtIdx, app.endIdx);
            app.yValSaline = [binData(1), binData(2)];
            app.BeginExperimentButton.Enable = 1;
            app.SalineCalibButton.Enable = 0;
             try
                  app.KD.Pump1.stop();
                  app.KD.Pump2.stop();
                  app.KD.Pump3.stop();
             catch
             end
        end

        % Button pushed function: BeginExperimentButton
        function BeginExperimentButtonPushed(app, event)
             app.EndExperimentButton.Enable = 1;
            app.BeginExperimentButton.Enable = 0;
            
            %Connect to pumps
            if app.Pump1CheckBox.Value
                app.KD.Pump1 = KDPump(strcat('COM',num2str(app.COMPump1Edit.Value)));
                app.KD.Pump1.infuseRate = app.SteadyPump1Edit.Value;
            end
            if app.Pump2CheckBox.Value
                app.KD.Pump2 = KDPump(strcat('COM',num2str(app.COMPump2Edit.Value)));
                app.KD.Pump2.infuseRate = app.SteadyPump2Edit.Value;
                app.AP.actuateMotor('A180')
                pause(0.01)
            end
             if app.Pump3CheckBox.Value
                app.KD.Pump3 = KDPump(strcat('COM',num2str(app.COMPump3Edit.Value)));
                app.KD.Pump3.infuseRate = app.SteadyPump3Edit.Value;
                app.AP.actuateMotor('C180')
                pause(0.01)
            end
            
            %Clear axes
            cla(app.AxConcMB);
            cla(app.AxConcTY);
            cla(app.AxInt424);
            cla(app.AxInt631);
            cla(app.AxesScale);
            
            %Start pump infusion
            if app.Pump1CheckBox.Value
                app.KD.Pump1.run();
            end
            if app.Pump2CheckBox.Value
                app.KD.Pump2.run();
            end    
            if app.Pump3CheckBox.Value
                app.KD.Pump3.run();
            end    
            
            %Connect to pumps
            if app.Pump1CheckBox.Value
                app.KD.Pump1 = KDPump(strcat('COM',num2str(app.COMPump1Edit.Value)));
                app.KD.Pump1.infuseRate = app.SteadyPump1Edit.Value;
            end
            if app.Pump2CheckBox.Value
                app.KD.Pump2 = KDPump(strcat('COM',num2str(app.COMPump2Edit.Value)));
                app.KD.Pump2.infuseRate = app.SteadyPump2Edit.Value;
                app.AP.actuateMotor('A180')
                pause(0.01)
            end
             if app.Pump3CheckBox.Value
                app.KD.Pump3 = KDPump(strcat('COM',num2str(app.COMPump3Edit.Value)));
                app.KD.Pump3.infuseRate = app.SteadyPump3Edit.Value;
                app.AP.actuateMotor('C180')
                pause(0.01)
            end
           
            %Set the experiment start flag to high and file Indx to 1
            app.expStartFlag = 1;
            app.fileIdx = 1; 
            tic;
            
        end

        % Button pushed function: EndExperimentButton
        function EndExperimentButtonPushed(app, event)
            % Stop acquisition
            app.StartAcquisitionSwitch.Value = 'Off';
            
            % Stop pumps 
            try
                app.KD.Pump1.stop();
                app.KD.Pump2.stop();
                app.KD.Pump3.stop();
            catch
            end
            
            try
                app.KD.Pump1.stop();
                app.KD.Pump3.stop();
                app.KD.Pump2.stop();                
            catch
            end
            
            
            % Write xlsData
            if strcmp(app.DataLoggerSwitch.Value, 'On')
                csvwrite(fullfile(app.path,app.filename), [app.timeVec(1:app.fileIdx-1); app.binWavelength(:,1:app.fileIdx-1); app.scaleData(1:app.fileIdx-1)]');
                writetable(cell2table([{'MB';'TY'; 'Saline'},num2cell([app.yValDrug(1); app.yValTY(1); app.yValSaline(1)]),num2cell([app.yValDrug(2); app.yValTY(2); app.yValSaline(2)])]),fullfile(app.path,strcat(app.filename(1:end-4),'_metadata.csv')),'writevariablenames',0)
            end
           
           
        end

        % Callback function
        function StartAcquisitionSwitchScaleValueChanged(app, event)
           

             if strcmp(app.StartAcquisitionSwitchScale.Value, 'Off')
                app.StatusLampScale.Color = [1 0.5 0];
                return
            end
            % Iteratively update the AxesSpec
            app.StatusLampScale.Color = [0 1 0];
            tic
           while strcmp(app.StartAcquisitionSwitchScale.Value, 'On')
             % Acquire and calculate   
            if app.scaleStartFlag && app.CheckBoxScale.Value
             
                 app.ScaleObj = Anisworth('COM',num2str(app.COMScaleEdit.Value));
                 cla(app.AxesScale);
                 plot(app.AxesScale, toc/60,  M.mass, '.b', 'MarkerSize', 10);
           end
             % Log data if the experiment has started
             if app.expStartFlag
                 app.binWavelength(:,app.fileIdx) = binData;
                 app.timeVec(app.fileIdx) = toc;
                 
                 app.fileIdx = app.fileIdx+1;
                 if app.tMax-toc < 0
                     % Call the experiment end triggers
                     break
                 end
                 pause((app.fileIdx-1)*app.AcquisitionIntervalEdit.Value - toc);  
             else
                 pause(app.AcquisitionIntervalEdit.Value);
             end
             
           end
    
        end

        % Value changed function: CheckBoxScale
        function CheckBoxScaleValueChanged(app, event)
           if app.CheckBoxScale.Value && isempty(app.ScaleObj)
               app.ScaleObj = Ainsworth(strcat('COM',num2str(app.COMScaleEdit.Value)));
           end
            
        end

        % Button pushed function: SalineFlushButton
        function SalineFlushButtonPushed(app, event)
            app.AP.actuateMotor('A0,C0,D180')
            pause(0.01) 
            
            
            %Change flow rates
            app.KD.Pump1 = KDPump(strcat('COM',num2str(app.COMPump1Edit.Value)));
            app.KD.Pump1.infuseRate = 400;
            app.KD.Pump1.run();
            % Imposta un timer con intervallo di 1 secondo
            app.timer = timer;
            app.timer.Period = 1; % ripete ogni secondo
            app.timer.ExecutionMode = 'fixedRate';
            app.timer.TimerFcn = @(~,~) timerCallback(app);
            app.timer.StartDelay = 1; % parte dopo 1 secondo
            app.timeElapsed = 0; % tempo trascorso inizializzato a 0
            start(app.timer);
             end
          % Timer callback function
        function timerCallback(app)
            app.timeElapsed = app.timeElapsed + 1; % Aggiorna il tempo trascorso
        
            if app.timeElapsed == 40
                % Ferma la pompa dopo 30 secondi
                try
                app.KD.Pump1.stop();
            catch
            end
            elseif app.timeElapsed == 45
                % Attiva i motori dopo ulteriori 5 secondi
                app.AP.actuateMotor('A0,C0,D180');
                stop(app.timer); % Ferma il timer
                delete(app.timer); % Elimina il timer
            end
        end

        % Button pushed function: TGFlushButton
        function TGFlushButtonPushed(app, event)
             app.AP.actuateMotor('A0,C90,D180')
            pause(0.01)
                      
            %Change flow rates
            app.KD.Pump3 = KDPump(strcat('COM',num2str(app.COMPump3Edit.Value)));
            app.KD.Pump3.infuseRate = 400;
            app.KD.Pump3.run();
           % Imposta un timer con intervallo di 1 secondo
            app.timer = timer;
            app.timer.Period = 1; % ripete ogni secondo
            app.timer.ExecutionMode = 'fixedRate';
            app.timer.TimerFcn = @(~,~) timerCallback1(app);
            app.timer.StartDelay = 1; % parte dopo 1 secondo
            app.timeElapsed = 0; % tempo trascorso inizializzato a 0
            start(app.timer);
         
        end
          % Timer callback function
        function timerCallback1(app)
            app.timeElapsed = app.timeElapsed + 1; % Aggiorna il tempo trascorso
        
            if app.timeElapsed == 30
                % Ferma la pompa dopo 30 secondi
              try
                app.KD.Pump3.stop();
             catch
             end
             elseif app.timeElapsed == 35
                % Attiva i motori dopo ulteriori 5 secondi
                app.AP.actuateMotor('A0,C0,D180');
                stop(app.timer); % Ferma il timer
                delete(app.timer); % Elimina il timer
            end
            
        end

        % Button pushed function: ERIFlushButton
        function ERIFlushButtonPushed(app, event)
            %Actuate the servo to Flush MB
        app.AP.actuateMotor('A90,C0,D180')
        pause(0.01)
        
        % Change flow rates
        app.KD.Pump2 = KDPump(strcat('COM', num2str(app.COMPump2Edit.Value)));
        app.KD.Pump2.infuseRate = 400;
        app.KD.Pump2.run();
        pause(0.01)
        % Imposta un timer con intervallo di 1 secondo
        app.timer = timer;
        app.timer.Period = 1; % ripete ogni secondo
        app.timer.ExecutionMode = 'fixedRate';
        app.timer.TimerFcn = @(~,~) timerCallback2(app);
        app.timer.StartDelay = 1; % parte dopo 1 secondo
        app.timeElapsed = 0; % tempo trascorso inizializzato a 0
        start(app.timer);
        end

        % Timer callback function
        function timerCallback2(app)
            app.timeElapsed = app.timeElapsed + 1; % Aggiorna il tempo trascorso
        
            if app.timeElapsed == 30
                % Ferma la pompa dopo 30 secondi
                try
                    app.KD.Pump2.stop();
                catch
                end
            elseif app.timeElapsed == 35
                % Attiva i motori dopo ulteriori 5 secondi
                app.AP.actuateMotor('A0,C0,D180');
                stop(app.timer); % Ferma il timer
                delete(app.timer); % Elimina il timer
            end
                
            
        end

        % Callback function
        function Change_RATEMBNCButtonPushed(app, event)
            pp.KD.Pump1.infuseRate = app.SteadyPump1Edit.Value;
            app.KD.Pump2.infuseRate = 2*app.SteadyPump2Edit.Value;
            app.KD.Pump3.infuseRate = app.SteadyPump3Edit.Value;    
            pause(0.01)
        end

        % Value changed function: MaxPump1Edit
        function MaxPump1EditValueChanged(app, event)
            value = app.MaxPump1Edit.Value;
            
        end

        % Value changed function: ManualSwitch
        function ManualSwitchValueChanged(app, event)
              
            if app.ManualSwitch.Value == "On"
             app.ActualLabel.Enable = 1;
             pause(0.001);
             app.Actual1Edit.Enable = 1;
                pause(0.001);
             app.Actual2Edit.Enable = 1;
                pause(0.001);
             app.Actual3Edit.Enable = 1;
            else 
             app.ActualLabel.Enable = 0;
                pause(0.001);
             app.Actual1Edit.Enable = 0;
                pause(0.001);
             app.Actual2Edit.Enable = 0;
                pause(0.001);
             app.Actual3Edit.Enable = 0;
            end
            value = app.ManualSwitch.Value;
        end

        % Value changed function: Actual1Edit
        function Actual1EditValueChanged(app, event)
            value = app.Actual1Edit.Value;
                app.Actual1Edit.Value = app.MaxPump1Edit.Value;

        end

        % Value changed function: Actual2Edit
        function Actual2EditValueChanged(app, event)
            value = app.Actual2Edit.Value;
                            app.Actual2Edit.Value = app.MaxPump2Edit.Value;

        end

        % Value changed function: Actual3Edit
        function Actual3EditValueChanged(app, event)
            value = app.Actual3Edit.Value;
                             app.Actual3Edit.Value = app.MaxPump3Edit.Value;

        end

        % Callback function
        function SMARTTestBedUIFigureSizeChanged(app, event)
            position = app.SMARTTestBedUIFigure.Position;
            
        end

        % Button pushed function: ConnArduino
        function ConnArduinoButtonPushed(app, event)
             %Connect to ArduinoPump
            
             app.AP = ArduinoPump('COM6');
             
            pause(0.5)
            app.AP.actuateMotor('D180');
            pause(0.1)
            app.AP.actuateMotor('A0');
            pause(0.1)
            app.AP.actuateMotor('C0');
            pause(0.1)
            app.AP.actuateMotor('D180');
        end

        % Key press function: SMARTTestBedUIFigure
        function SMARTTestBedUIFigureKeyPress(app, event)
            key = event.Key;
          if app.ManualSwitch.Value == "On"
          switch key
            case 'w'
                %Change flow rates
               app.KD.Pump1 = KDPump(strcat('COM',num2str(app.COMPump1Edit.Value)));
               app.KD.Pump1.infuseRate = app.MaxPump1Edit.Value;
                app.KD.Pump1.run();
                app.Actual1Edit.Value = app.MaxPump1Edit.Value;
            case 'e'
                %Change flow rates
               app.KD.Pump2 = KDPump(strcat('COM',num2str(app.COMPump2Edit.Value)));
               app.KD.Pump2.infuseRate = app.MaxPump2Edit.Value;
               app.KD.Pump2.run();
                app.Actual2Edit.Value = app.MaxPump2Edit.Value;
                
            case 'r'    
                 %Change flow rates
               app.KD.Pump3 = KDPump(strcat('COM',num2str(app.COMPump3Edit.Value)));
               app.KD.Pump3.infuseRate = app.MaxPump3Edit.Value;
               app.KD.Pump3.run();
                 app.Actual3Edit.Value = app.MaxPump3Edit.Value;
            case 's'
                %Change flow rates
                app.KD.Pump1 = KDPump(strcat('COM',num2str(app.COMPump1Edit.Value)));
                app.KD.Pump1.infuseRate = app.SteadyPump1Edit.Value;
                app.KD.Pump1.run();
                app.Actual1Edit.Value = app.SteadyPump1Edit.Value;
            case 'd'
                %Change flow rates
                app.KD.Pump2 = KDPump(strcat('COM',num2str(app.COMPump2Edit.Value)));
                app.KD.Pump2.infuseRate = app.SteadyPump2Edit.Value;
                app.KD.Pump2.run();
                 app.Actual2Edit.Value = app.SteadyPump2Edit.Value;
            case 'f'     
                %Change flow rates
                app.KD.Pump3 = KDPump(strcat('COM',num2str(app.COMPump3Edit.Value)));
                app.KD.Pump3.infuseRate = app.SteadyPump3Edit.Value;
                app.KD.Pump3.run();
                 app.Actual3Edit.Value = app.SteadyPump3Edit.Value;
             end    

          end


        end

        % Button pushed function: UpdateFlowButton
        function UpdateFlowButtonPushed(app, event)
            
        end

        % Button pushed function: TYCalibButton
        function TYCalibButtonPushed(app, event)
             %maxVal = max(app.specdata.value);
            binData = app.TS.getBinnedDataHE(app.specdata.value, app.strtIdx, app.endIdx);
            app.yValTY = [binData(1), binData(2)];
            app.SalineCalibButton.Enable = 1;
            app.ERICalibButton.Enable = 0;
            app.TYCalibButton.Enable = 0;
              try
                  app.KD.Pump1.stop();
                  app.KD.Pump2.stop();
                  app.KD.Pump3.stop();
              catch
              end
        end

        % Value changed function: StartCalculationSwitch
        function StartCalculationSwitchValueChanged(app, event)
            value = app.StartCalculationSwitch.Value;
            app.StatusLamp_3.Color = [0 1 0];
 
        end

        % Button pushed function: testbuttonButton
        function testbuttonButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SMARTTestBedUIFigure = uifigure('Visible', 'off');
            app.SMARTTestBedUIFigure.Name = 'SMART Test Bed';
            app.SMARTTestBedUIFigure.CloseRequestFcn = createCallbackFcn(app, @SMARTTestBedUIFigureCloseRequest, true);
            app.SMARTTestBedUIFigure.KeyPressFcn = createCallbackFcn(app, @SMARTTestBedUIFigureKeyPress, true);
            app.SpectrophometerPanel = uipanel(app.SMARTTestBedUIFigure);
            app.SpectrophometerPanel.Title = 'Spectrophometer';
            app.AxesSpec = uiaxes(app.SpectrophometerPanel);
            title(app.AxesSpec, 'Real Time Spectrum')
            xlabel(app.AxesSpec, 'Wavelength(nm)')
            ylabel(app.AxesSpec, 'Intensity')
            app.AxesSpec.XLim = [300 800];
            app.AxInt631 = uiaxes(app.SpectrophometerPanel);
            title(app.AxInt631, 'Real Time Intensity 631 EG')
            xlabel(app.AxInt631, 'Time (min)')
            ylabel(app.AxInt631, 'Intensity')
            app.AxInt631.YLim = [-0.1 2];
            app.AxInt424 = uiaxes(app.SpectrophometerPanel);
            title(app.AxInt424, 'Real Time Intensity 424 TZ')
            xlabel(app.AxInt424, 'Time (min)')
            ylabel(app.AxInt424, 'Intensity')
            app.AxInt424.YLim = [-0.1 2];
            app.IntegrationTimesEditFieldLabel = uilabel(app.SpectrophometerPanel);
            app.IntegrationTimesEditFieldLabel.Text = 'Integration Time (s):';
            app.IntegrationTimesEdit = uieditfield(app.SpectrophometerPanel, 'numeric');
            app.IntegrationTimesEdit.ValueChangedFcn = createCallbackFcn(app, @IntegrationTimesEditValueChanged, true);
            app.IntegrationTimesEdit.Value = 0.001;
            app.BinWavelength1nmEditFieldLabel = uilabel(app.SpectrophometerPanel);
            app.BinWavelength1nmEditFieldLabel.Text = 'Bin Wavelength 1 (nm)';
            app.BinWavelength1nmEdit = uieditfield(app.SpectrophometerPanel, 'numeric');
            app.BinWavelength1nmEdit.ValueChangedFcn = createCallbackFcn(app, @BinWavelength1nmEditValueChanged, true);
            app.BinWavelength1nmEdit.Value = 424;
            app.BinWavelength2nmEditFieldLabel = uilabel(app.SpectrophometerPanel);
            app.BinWavelength2nmEditFieldLabel.Text = 'Bin Wavelength 2 (nm)';
            app.BinWavelength2nmEdit = uieditfield(app.SpectrophometerPanel, 'numeric');
            app.BinWavelength2nmEdit.ValueChangedFcn = createCallbackFcn(app, @BinWavelength2nmEditValueChanged, true);
            app.BinWavelength2nmEdit.Value = 631;
            app.BinHalfWidthEditFieldLabel = uilabel(app.SpectrophometerPanel);
            app.BinHalfWidthEditFieldLabel.Text = 'Bin Half-Width';
            app.BinHalfWidth1Edit = uieditfield(app.SpectrophometerPanel, 'numeric');
            app.BinHalfWidth1Edit.ValueChangedFcn = createCallbackFcn(app, @BinHalfWidth1EditValueChanged, true);
            app.BinHalfWidth1Edit.Value = 1;
            app.BinHalfWidthEditField_2Label = uilabel(app.SpectrophometerPanel);
            app.BinHalfWidthEditField_2Label.Text = 'Bin Half-Width';
            app.BinHalfWidth2Edit = uieditfield(app.SpectrophometerPanel, 'numeric');
            app.BinHalfWidth2Edit.ValueChangedFcn = createCallbackFcn(app, @BinHalfWidth2EditValueChanged, true);
            app.BinHalfWidth2Edit.Value = 1;
            app.StartAcquisitionSwitchLabel = uilabel(app.SpectrophometerPanel);
            app.StartAcquisitionSwitchLabel.Text = 'Start Acquisition';
            app.StartAcquisitionSwitch = uiswitch(app.SpectrophometerPanel, 'slider');
            app.StartAcquisitionSwitch.ValueChangedFcn = createCallbackFcn(app, @StartAcquisitionSwitchValueChanged, true);
            app.StatusLampLabel = uilabel(app.SpectrophometerPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.SpectrophometerPanel);
            app.AcquisitionIntervalsLabel = uilabel(app.SpectrophometerPanel);
            app.AcquisitionIntervalsLabel.Text = 'Acquisition Interval (s)';
            app.AcquisitionIntervalEdit = uieditfield(app.SpectrophometerPanel, 'numeric');
            app.AcquisitionIntervalEdit.ValueChangedFcn = createCallbackFcn(app, @AcquisitionIntervalEditValueChanged, true);
            app.AcquisitionIntervalEdit.Value = 0.2;
            app.PumpsPanel = uipanel(app.SMARTTestBedUIFigure);
            app.PumpsPanel.Title = 'Pumps';
            app.Pump1CheckBox = uicheckbox(app.PumpsPanel);
            app.Pump1CheckBox.Text = 'Pump 1';
            app.COMEditFieldLabel = uilabel(app.PumpsPanel);
            app.COMEditFieldLabel.Text = 'COM:';
            app.COMPump1Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.COMPump1Edit.Value = 12;
            app.SteadyLabel = uilabel(app.PumpsPanel);
            app.SteadyLabel.Text = 'Steady';
            app.SteadyPump1Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.SteadyPump1Edit.Value = 1.5;
            app.MaxLabel = uilabel(app.PumpsPanel);
            app.MaxLabel.Text = 'Max';
            app.MaxPump1Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.MaxPump1Edit.ValueChangedFcn = createCallbackFcn(app, @MaxPump1EditValueChanged, true);
            app.MaxPump1Edit.Value = 4.5;
            app.Pump2CheckBox = uicheckbox(app.PumpsPanel);
            app.Pump2CheckBox.Text = 'Pump 2';
            app.COMEditFieldLabel_2 = uilabel(app.PumpsPanel);
            app.COMEditFieldLabel_2.Text = 'COM:';
            app.COMPump2Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.COMPump2Edit.Value = 4;
            app.SteadyLabel_2 = uilabel(app.PumpsPanel);
            app.SteadyLabel_2.Text = 'Steady';
            app.SteadyPump2Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.SteadyPump2Edit.Value = 0.5;
            app.MaxLabel_2 = uilabel(app.PumpsPanel);
            app.MaxLabel_2.Text = 'Max';
            app.MaxPump2Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.MaxPump2Edit.Value = 1.5;
            app.SteadyPump1mlhr = uilabel(app.PumpsPanel);
            app.SteadyPump1mlhr.Text = 'ml/hr';
            app.SteadyPump2mlhr = uilabel(app.PumpsPanel);
            app.SteadyPump2mlhr.Text = 'ml/hr';
            app.MaxPump1mlhr = uilabel(app.PumpsPanel);
            app.MaxPump1mlhr.Text = 'ml/hr';
            app.MaxPump2mlhr = uilabel(app.PumpsPanel);
            app.MaxPump2mlhr.Text = 'ml/hr';
            app.Pump3CheckBox = uicheckbox(app.PumpsPanel);
            app.Pump3CheckBox.Text = 'Pump 3';
            app.COMPump3Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.COMPump3Edit.Value = 13;
            app.MaxPump3mlhr = uilabel(app.PumpsPanel);
            app.MaxPump3mlhr.Text = 'ml/hr';
            app.COMEditFieldLabel_4 = uilabel(app.PumpsPanel);
            app.COMEditFieldLabel_4.Text = 'COM:';
            app.SteadyLabel_4 = uilabel(app.PumpsPanel);
            app.SteadyLabel_4.Text = 'Steady';
            app.SteadyPump3Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.SteadyPump3Edit.Value = 1;
            app.SteadyPump2mlhr_2 = uilabel(app.PumpsPanel);
            app.SteadyPump2mlhr_2.Text = 'ml/hr';
            app.MaxLabel_3 = uilabel(app.PumpsPanel);
            app.MaxLabel_3.Text = 'Max';
            app.MaxPump3Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.MaxPump3Edit.Value = 3;
            app.DropDown = uidropdown(app.PumpsPanel);
            app.DropDown.Items = {'EG', 'TZ'};
            app.DropDown.Value = 'EG';
            app.DropDown_2 = uidropdown(app.PumpsPanel);
            app.DropDown_2.Items = {'EG', 'TZ'};
            app.DropDown_2.Value = 'TZ';
            app.ManualSwitchLabel = uilabel(app.PumpsPanel);
            app.ManualSwitchLabel.Text = 'Manual';
            app.ManualSwitch = uiswitch(app.PumpsPanel, 'toggle');
            app.ManualSwitch.ValueChangedFcn = createCallbackFcn(app, @ManualSwitchValueChanged, true);
            app.ActualLabel = uilabel(app.PumpsPanel);
            app.ActualLabel.Enable = 'off';
            app.ActualLabel.Text = 'Actual';
            app.EditFieldLabel = uilabel(app.PumpsPanel);
            app.EditFieldLabel.Enable = 'off';
            app.EditFieldLabel.Text = '';
            app.Actual1Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.Actual1Edit.ValueChangedFcn = createCallbackFcn(app, @Actual1EditValueChanged, true);
            app.Actual1Edit.Enable = 'off';
            app.EditFieldLabel_2 = uilabel(app.PumpsPanel);
            app.EditFieldLabel_2.Enable = 'off';
            app.EditFieldLabel_2.Text = '';
            app.Actual2Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.Actual2Edit.ValueChangedFcn = createCallbackFcn(app, @Actual2EditValueChanged, true);
            app.Actual2Edit.Enable = 'off';
            app.EditFieldLabel_3 = uilabel(app.PumpsPanel);
            app.EditFieldLabel_3.Enable = 'off';
            app.EditFieldLabel_3.Text = '';
            app.Actual3Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.Actual3Edit.ValueChangedFcn = createCallbackFcn(app, @Actual3EditValueChanged, true);
            app.Actual3Edit.Enable = 'off';
            app.UpdateFlowButton = uibutton(app.PumpsPanel, 'push');
            app.UpdateFlowButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateFlowButtonPushed, true);
            app.UpdateFlowButton.Text = 'Update Flow';
            app.testbuttonButton = uibutton(app.PumpsPanel, 'push');
            app.testbuttonButton.ButtonPushedFcn = createCallbackFcn(app, @testbuttonButtonPushed, true);
            app.testbuttonButton.Text = 'test button';
            app.SMARTTestBedLabel = uilabel(app.SMARTTestBedUIFigure);
            app.SMARTTestBedLabel.Text = 'SMART Test Bed';
            app.ExperimentStepsLabel = uilabel(app.SMARTTestBedUIFigure);
            app.ExperimentStepsLabel.Text = 'Experiment Steps';
            app.ERICalibButton = uibutton(app.SMARTTestBedUIFigure, 'push');
            app.ERICalibButton.ButtonPushedFcn = createCallbackFcn(app, @ERICalibButtonPushed, true);
            app.ERICalibButton.Enable = 'off';
            app.ERICalibButton.Text = 'ERI Calib';
            app.SalineCalibButton = uibutton(app.SMARTTestBedUIFigure, 'push');
            app.SalineCalibButton.ButtonPushedFcn = createCallbackFcn(app, @SalineCalibButtonPushed, true);
            app.SalineCalibButton.Enable = 'off';
            app.SalineCalibButton.Text = 'Saline Calib';
            app.BeginExperimentButton = uibutton(app.SMARTTestBedUIFigure, 'push');
            app.BeginExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @BeginExperimentButtonPushed, true);
            app.BeginExperimentButton.Enable = 'off';
            app.BeginExperimentButton.Text = 'Begin Experiment';
            app.EndExperimentButton = uibutton(app.SMARTTestBedUIFigure, 'push');
            app.EndExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @EndExperimentButtonPushed, true);
            app.EndExperimentButton.Enable = 'off';
            app.EndExperimentButton.Text = 'End Experiment';
            app.DataLoggerLabel = uilabel(app.SMARTTestBedUIFigure);
            app.DataLoggerLabel.Text = 'Data Logger';
            app.DataLoggerSwitch = uiswitch(app.SMARTTestBedUIFigure, 'slider');
            app.DataLoggerSwitch.ValueChangedFcn = createCallbackFcn(app, @DataLoggerSwitchValueChanged, true);
            app.DataLoggerLamp = uilamp(app.SMARTTestBedUIFigure);
            app.FileEditFieldLabel = uilabel(app.SMARTTestBedUIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.SMARTTestBedUIFigure, 'text');
            app.FileEditField.Editable = 'off';
            app.FileLoadButton = uibutton(app.SMARTTestBedUIFigure, 'push');
            app.FileLoadButton.ButtonPushedFcn = createCallbackFcn(app, @FileLoadButtonPushed, true);
            app.FileLoadButton.Text = '..';
            app.ScalePanel = uipanel(app.SMARTTestBedUIFigure);
            app.ScalePanel.Title = 'Scale';
            app.AxesScale = uiaxes(app.ScalePanel);
            title(app.AxesScale, 'Real Time Mass')
            xlabel(app.AxesScale, 'Time (min)')
            ylabel(app.AxesScale, 'Mass (mg)')
            zlabel(app.AxesScale, 'Z')
            app.COMEditFieldLabel_3 = uilabel(app.ScalePanel);
            app.COMEditFieldLabel_3.Text = 'COM:';
            app.COMScaleEdit = uieditfield(app.ScalePanel, 'numeric');
            app.COMScaleEdit.Value = 9;
            app.StatusLamp_2Label = uilabel(app.ScalePanel);
            app.StatusLamp_2Label.Text = 'Status';
            app.StatusLampScale = uilamp(app.ScalePanel);
            app.CheckBoxScale = uicheckbox(app.ScalePanel);
            app.CheckBoxScale.ValueChangedFcn = createCallbackFcn(app, @CheckBoxScaleValueChanged, true);
            app.CheckBoxScale.Text = '';
            app.DropDown_3 = uidropdown(app.ScalePanel);
            app.DropDown_3.Items = {'Mass', 'Flow Rate'};
            app.DropDown_3.Value = 'Mass';
            app.ERIFlushButton = uibutton(app.SMARTTestBedUIFigure, 'push');
            app.ERIFlushButton.ButtonPushedFcn = createCallbackFcn(app, @ERIFlushButtonPushed, true);
            app.ERIFlushButton.Text = 'ERI Flush';
            app.TGFlushButton = uibutton(app.SMARTTestBedUIFigure, 'push');
            app.TGFlushButton.ButtonPushedFcn = createCallbackFcn(app, @TGFlushButtonPushed, true);
            app.TGFlushButton.Text = 'TG Flush';
            app.SalineFlushButton = uibutton(app.SMARTTestBedUIFigure, 'push');
            app.SalineFlushButton.ButtonPushedFcn = createCallbackFcn(app, @SalineFlushButtonPushed, true);
            app.SalineFlushButton.Text = 'Saline Flush';
            app.ConnArduino = uibutton(app.SMARTTestBedUIFigure, 'push');
            app.ConnArduino.ButtonPushedFcn = createCallbackFcn(app, @ConnArduinoButtonPushed, true);
            app.ConnArduino.Text = 'Connect to Arduino';
            app.TYCalibButton = uibutton(app.SMARTTestBedUIFigure, 'push');
            app.TYCalibButton.ButtonPushedFcn = createCallbackFcn(app, @TYCalibButtonPushed, true);
            app.TYCalibButton.Enable = 'off';
            app.TYCalibButton.Text = 'TY Calib';
            app.RealTimeConcentrationPanel = uipanel(app.SMARTTestBedUIFigure);
            app.RealTimeConcentrationPanel.Title = 'Real Time Concentration';
            app.AxConcTY = uiaxes(app.RealTimeConcentrationPanel);
            title(app.AxConcTY, 'Real Time Concentration TZ')
            xlabel(app.AxConcTY, 'Time (min)')
            ylabel(app.AxConcTY, 'C/C_p ')
            app.AxConcMB = uiaxes(app.RealTimeConcentrationPanel);
            title(app.AxConcMB, 'Real Time Concentration EG')
            xlabel(app.AxConcMB, 'Time (min)')
            ylabel(app.AxConcMB, 'C/C_p ')
            app.StartCalculationSwitchLabel = uilabel(app.RealTimeConcentrationPanel);
            app.StartCalculationSwitchLabel.Text = 'Start Calculation';
            app.StartCalculationSwitch = uiswitch(app.RealTimeConcentrationPanel, 'slider');
            app.StartCalculationSwitch.ValueChangedFcn = createCallbackFcn(app, @StartCalculationSwitchValueChanged, true);
            app.StatusLamp_2Label_2 = uilabel(app.RealTimeConcentrationPanel);
            app.StatusLamp_2Label_2.Text = 'Status';
            app.StatusLamp_3 = uilamp(app.RealTimeConcentrationPanel);
            % Show the figure after all components are created
            app.SMARTTestBedUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Smart_Specto3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SMARTTestBedUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SMARTTestBedUIFigure)
        end
    end
end