classdef SmartTestBed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SMARTTestBedUIFigure            matlab.ui.Figure
        FileLoadButton                  matlab.ui.control.Button
        FileEditField                   matlab.ui.control.EditField
        FileEditFieldLabel              matlab.ui.control.Label
        DataLoggerLamp                  matlab.ui.control.Lamp
        DataLoggerSwitch                matlab.ui.control.Switch
        DataLoggerLabel                 matlab.ui.control.Label
        EndExperimentButton             matlab.ui.control.Button
        BeginExperimentButton           matlab.ui.control.Button
        SalineFlushButton               matlab.ui.control.Button
        DrugFlushButton                 matlab.ui.control.Button
        ExperimentStepsLabel            matlab.ui.control.Label
        SMARTTestBedLabel               matlab.ui.control.Label
        PumpsPanel                      matlab.ui.container.Panel
        MaxPump2mlhr                    matlab.ui.control.Label
        MaxPump1mlhr                    matlab.ui.control.Label
        SteadyPump2mlhr                 matlab.ui.control.Label
        SteadyPump1mlhr                 matlab.ui.control.Label
        Pump3Gauge                      matlab.ui.control.SemicircularGauge
        Pump3GaugeLabel                 matlab.ui.control.Label
        Pump2Gauge                      matlab.ui.control.SemicircularGauge
        Pump2GaugeLabel                 matlab.ui.control.Label
        Pump1Gauge                      matlab.ui.control.SemicircularGauge
        Pump1GaugeLabel                 matlab.ui.control.Label
        WtSlider                        matlab.ui.control.Slider
        WtSliderLabel                   matlab.ui.control.Label
        SMARTSwitch                     matlab.ui.control.ToggleSwitch
        SMARTSwitchLabel                matlab.ui.control.Label
        MaxPump2Edit                    matlab.ui.control.NumericEditField
        MaxLabel_2                      matlab.ui.control.Label
        SteadyPump2Edit                 matlab.ui.control.NumericEditField
        SteadyLabel_2                   matlab.ui.control.Label
        COMPump2Edit                    matlab.ui.control.NumericEditField
        COMEditFieldLabel_2             matlab.ui.control.Label
        Pump2CheckBox                   matlab.ui.control.CheckBox
        MaxPump1Edit                    matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        SteadyPump1Edit                 matlab.ui.control.NumericEditField
        SteadyLabel                     matlab.ui.control.Label
        COMPump1Edit                    matlab.ui.control.NumericEditField
        COMEditFieldLabel               matlab.ui.control.Label
        Pump1CheckBox                   matlab.ui.control.CheckBox
        AxesConc                        matlab.ui.control.UIAxes
        SpectrophometerPanel            matlab.ui.container.Panel
        AcquisitionIntervalEdit         matlab.ui.control.NumericEditField
        AcquisitionIntervalsLabel       matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        StartAcquisitionSwitch          matlab.ui.control.Switch
        StartAcquisitionSwitchLabel     matlab.ui.control.Label
        BinHalfWidth2Edit               matlab.ui.control.NumericEditField
        BinHalfWidthEditField_2Label    matlab.ui.control.Label
        BinHalfWidth1Edit               matlab.ui.control.NumericEditField
        BinHalfWidthEditFieldLabel      matlab.ui.control.Label
        BinWavelength2nmEdit            matlab.ui.control.NumericEditField
        BinWavelength2nmEditFieldLabel  matlab.ui.control.Label
        BinWavelength1nmEdit            matlab.ui.control.NumericEditField
        BinWavelength1nmEditFieldLabel  matlab.ui.control.Label
        IntegrationTimesEdit            matlab.ui.control.NumericEditField
        IntegrationTimesEditFieldLabel  matlab.ui.control.Label
        AxesSpec                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        specparams % Params of the spectrophotometer 
        TS %Instance of ThorSpectrophotometer class
        KD %Instances of the KDPump class
        wldata;
        specdata;
        
        binWavelength = zeros(2,108000);  % Store data from bin wavelenth
        timeVec = zeros(1,108000); %Store acquisition time
        
        strtIdx  = zeros(1,2); % Wavelength idx locations
        endIdx  = zeros(1,2); % Wavelength idx locations
        
        % CSV File
        filename = []; %Csv file name for storing data
        path = []; % Path for storing csv file
        
        % Baseline plots
        xValDrug = [300, 800];
        yValDrug = [nan, nan];
        xValSaline = [300, 800];
        yValSaline = [nan, nan];
        
        %Max acqusition time
        tMax = 3600*3; % Current experiments are capped at 3hrs once the experiment is started
        expStartFlag = 0; 
        fileIdx = 1;
    end

    methods (Access = public)
    
        function normConc = getNormConc(app, binIntensity)
            % Returns the normalized concentration based on the calibaration curve:
            % v1.0 Assumes max concentration is 100 ug/ml, can improve performance by avoiding Cp calculation here
            
            if isnan(app.yValSaline(1))
                normConc = binIntensity;
                return
            end
            
            scaledBinIntensity = (binIntensity - app.yValDrug(1))/(app.yValSaline(1) - app.yValDrug(1));
            %MB
            Cp = 100*(app.SteadyPump2Edit.Value)/(app.SteadyPump1Edit.Value + app.SteadyPump2Edit.Value);
            normConc = (-log(scaledBinIntensity)/0.04606)/Cp;
            
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create a instance of the ThorSpectrophotometer class
            addpath('./HardwareControlModule/');
            app.TS = ThorSpectrophotometer();
            app.TS.inttime = app.IntegrationTimesEdit.Value;
            
            % Change status light
            if app.TS.initError
            app.StatusLamp.Color = [1 0 0];
            else
            app.StatusLamp.Color = [1 0.5 0];
            end
            
            % Get Start and End Idx
            [app.wldata,app.specdata] = app.TS.getSpectrum;         
            [~, app.strtIdx(1)] =  min(abs(app.wldata.value - (app.BinWavelength1nmEdit.Value - app.BinHalfWidth1Edit.Value)));
            [~, app.endIdx(1)] = min(abs(app.wldata.value - (app.BinWavelength1nmEdit.Value + app.BinHalfWidth1Edit.Value)));
            [~, app.strtIdx(2)] =  min(abs(app.wldata.value - (app.BinWavelength2nmEdit.Value - app.BinHalfWidth2Edit.Value)));
            [~, app.endIdx(2)] = min(abs(app.wldata.value - (app.BinWavelength2nmEdit.Value + app.BinHalfWidth2Edit.Value)));
            
            % Axes prep
            hold(app.AxesConc, 'on');
            hold(app.AxesSpec, 'on');
            
            
        end

        % Value changed function: BinWavelength1nmEdit
        function BinWavelength1nmEditValueChanged(app, event)
            app.specparams.BinWavelength1 = app.BinWavelength1nmEdit.Value;         
        end

        % Value changed function: BinWavelength2nmEdit
        function BinWavelength2nmEditValueChanged(app, event)
            app.params.BinWavelength2 = app.BinWavelength2nmEdit.Value;         
        end

        % Value changed function: BinHalfWidth1Edit
        function BinHalfWidth1EditValueChanged(app, event)
            app.params.BinWavelength1 = app.BinHalfWidth1Edit.Value;          
        end

        % Value changed function: BinHalfWidth2Edit
        function BinHalfWidth2EditValueChanged(app, event)
            app.params.BinWavelength2 = app.BinHalfWidth2Edit.Value;        
        end

        % Close request function: SMARTTestBedUIFigure
        function SMARTTestBedUIFigureCloseRequest(app, event)
            app.TS.delete();
            delete(app)  
        end

        % Value changed function: IntegrationTimesEdit
        function IntegrationTimesEditValueChanged(app, event)
            app.TS.inttime = app.IntegrationTimesEdit.Value;
        end

        % Value changed function: StartAcquisitionSwitch
        function StartAcquisitionSwitchValueChanged(app, event)
          
            if strcmp(app.StartAcquisitionSwitch.Value, 'Off')
                app.StatusLamp.Color = [1 0.5 0];
                return
            end
            
            % Iteratively update the AxesSpec
            app.StatusLamp.Color = [0 1 0];
            app.DrugFlushButton.Enable =  1;
            
            tic
            while strcmp(app.StartAcquisitionSwitch.Value, 'On')
             % Acquire and calculate   
             [app.wldata,app.specdata] = app.TS.getSpectrum;
             binData = app.TS.getBinnedDataHE(app.specdata.value, app.strtIdx, app.endIdx);
             
             % Display data
             cla(app.AxesSpec);
             plot(app.AxesSpec, app.wldata.value,app.specdata.value, 'LineWidth', 1.5);
             plot(app.AxesSpec, app.xValDrug, app.yValDrug, '--k', 'LineWidth', 1.5);
             plot(app.AxesSpec, app.xValSaline, app.yValSaline, '--k', 'LineWidth', 1.5);
             
             plot(app.AxesConc, toc/60,  app.getNormConc(binData(2)), '.b', 'MarkerSize', 10);
             
             % Log data if the experiment has started
             if app.expStartFlag
                 app.binWavelength(:,app.fileIdx) = binData;
                 app.timeVec(app.fileIdx) = toc;
                 
                 app.fileIdx = app.fileIdx+1;
                 if app.tMax-toc < 0
                     % Call the experiment end triggers
                     break
                 end
                 pause((app.fileIdx-1)*app.AcquisitionIntervalEdit.Value - toc);  
             else
                 pause(app.AcquisitionIntervalEdit.Value);
             end
             
             
            end
            
        end

        % Value changed function: AcquisitionIntervalEdit
        function AcquisitionIntervalEditValueChanged(app, event)
            if app.AcquisitionIntervalEdit.Value<0.1
                app.AcquisitionIntervalEdit.Value = 0.1;
            end
        end

        % Value changed function: DataLoggerSwitch
        function DataLoggerSwitchValueChanged(app, event)
             if strcmp(app.DataLoggerSwitch.Value, 'On')
                app.DataLoggerLamp.Color = [1 0.5 0];
             else
                app.DataLoggerLamp.Color = [0.94 0.94 0.94]; 
             end
            
        end

        % Button pushed function: FileLoadButton
        function FileLoadButtonPushed(app, event)
            [app.filename,app.path] = uiputfile({'*.csv'});
            app.FileEditField.Value = app.filename;
            % Bring UI to the front
            figure(app.SMARTTestBedUIFigure);
            
        end

        % Button pushed function: DrugFlushButton
        function DrugFlushButtonPushed(app, event)
            %maxVal = max(app.specdata.value);
            binData = app.TS.getBinnedDataHE(app.specdata.value, app.strtIdx, app.endIdx);
            app.yValDrug = [binData(2), binData(2)];
            app.SalineFlushButton.Enable = 1;
            app.DrugFlushButton.Enable = 0;
        end

        % Button pushed function: SalineFlushButton
        function SalineFlushButtonPushed(app, event)
            %maxVal = max(app.specdata.value);
            binData = app.TS.getBinnedDataHE(app.specdata.value, app.strtIdx, app.endIdx);
            app.yValSaline = [binData(2), binData(2)];
            app.BeginExperimentButton.Enable = 1;
            app.SalineFlushButton.Enable = 0;
        end

        % Button pushed function: BeginExperimentButton
        function BeginExperimentButtonPushed(app, event)
            app.EndExperimentButton.Enable = 1;
            app.BeginExperimentButton.Enable = 0;
            
            %Connect to pumps
            if app.Pump1CheckBox.Value
                app.KD.Pump1 = KDPump(strcat('COM',num2str(app.COMPump1Edit.Value)));
                app.KD.Pump1.infuseRate = app.SteadyPump1Edit.Value;
            end
            if app.Pump2CheckBox.Value
                app.KD.Pump2 = KDPump(strcat('COM',num2str(app.COMPump2Edit.Value)));
                app.KD.Pump2.infuseRate = app.SteadyPump2Edit.Value;
            end
            
            %Clear axes
            cla(app.AxesConc);
            
            %Start pump infusion
            if app.Pump1CheckBox.Value
                app.KD.Pump1.run();
            end
            if app.Pump2CheckBox.Value
                app.KD.Pump2.run();
            end    
            
            %Set the experiment start flag to high and file Indx to 1
            app.expStartFlag = 1;
            app.fileIdx = 1; 
            tic;
            
        end

        % Button pushed function: EndExperimentButton
        function EndExperimentButtonPushed(app, event)
            % Stop acquisition
            app.StartAcquisitionSwitch.Value = 'Off';
            
            % Stop pumps 
            try
                app.KD.Pump1.stop();
                app.KD.Pump2.stop();
            catch
            end
            
            % Write xlsData
            if strcmp(app.DataLoggerSwitch.Value, 'On')
                csvwrite(fullfile(app.path,app.filename), [app.timeVec(1:app.fileIdx-1); app.binWavelength(:,1:app.fileIdx-1)]');
                writetable(cell2table([{'Drug'; 'Saline'},num2cell([app.yValDrug(1); app.yValSaline(1)])]),fullfile(app.path,strcat(app.filename(1:end-4),'_metadata.csv')),'writevariablenames',0)
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SMARTTestBedUIFigure = uifigure('Visible', 'off');
            app.SMARTTestBedUIFigure.Name = 'SMART Test Bed';
            app.SMARTTestBedUIFigure.CloseRequestFcn = createCallbackFcn(app, @SMARTTestBedUIFigureCloseRequest, true);
            app.SpectrophometerPanel = uipanel(app.SMARTTestBedUIFigure);
            app.SpectrophometerPanel.Title = 'Spectrophometer';
            app.AxesSpec = uiaxes(app.SpectrophometerPanel);
            title(app.AxesSpec, 'Real Time Spectrum')
            xlabel(app.AxesSpec, 'Wavelength(nm)')
            ylabel(app.AxesSpec, 'Intensity')
            app.AxesSpec.XLim = [300 800];
            app.AxesSpec.YLim = [0 1];
            app.IntegrationTimesEditFieldLabel = uilabel(app.SpectrophometerPanel);
            app.IntegrationTimesEditFieldLabel.Text = 'Integration Time (s):';
            app.IntegrationTimesEdit = uieditfield(app.SpectrophometerPanel, 'numeric');
            app.IntegrationTimesEdit.ValueChangedFcn = createCallbackFcn(app, @IntegrationTimesEditValueChanged, true);
            app.IntegrationTimesEdit.Value = 0.0001;
            app.BinWavelength1nmEditFieldLabel = uilabel(app.SpectrophometerPanel);
            app.BinWavelength1nmEditFieldLabel.Text = 'Bin Wavelength 1 (nm)';
            app.BinWavelength1nmEdit = uieditfield(app.SpectrophometerPanel, 'numeric');
            app.BinWavelength1nmEdit.ValueChangedFcn = createCallbackFcn(app, @BinWavelength1nmEditValueChanged, true);
            app.BinWavelength1nmEdit.Value = 430;
            app.BinWavelength2nmEditFieldLabel = uilabel(app.SpectrophometerPanel);
            app.BinWavelength2nmEditFieldLabel.Text = 'Bin Wavelength 2 (nm)';
            app.BinWavelength2nmEdit = uieditfield(app.SpectrophometerPanel, 'numeric');
            app.BinWavelength2nmEdit.ValueChangedFcn = createCallbackFcn(app, @BinWavelength2nmEditValueChanged, true);
            app.BinWavelength2nmEdit.Value = 630;
            app.BinHalfWidthEditFieldLabel = uilabel(app.SpectrophometerPanel);
            app.BinHalfWidthEditFieldLabel.Text = 'Bin Half-Width';
            app.BinHalfWidth1Edit = uieditfield(app.SpectrophometerPanel, 'numeric');
            app.BinHalfWidth1Edit.ValueChangedFcn = createCallbackFcn(app, @BinHalfWidth1EditValueChanged, true);
            app.BinHalfWidth1Edit.Value = 1;
            app.BinHalfWidthEditField_2Label = uilabel(app.SpectrophometerPanel);
            app.BinHalfWidthEditField_2Label.Text = 'Bin Half-Width';
            app.BinHalfWidth2Edit = uieditfield(app.SpectrophometerPanel, 'numeric');
            app.BinHalfWidth2Edit.ValueChangedFcn = createCallbackFcn(app, @BinHalfWidth2EditValueChanged, true);
            app.BinHalfWidth2Edit.Value = 1;
            app.StartAcquisitionSwitchLabel = uilabel(app.SpectrophometerPanel);
            app.StartAcquisitionSwitchLabel.Text = 'Start Acquisition';
            app.StartAcquisitionSwitch = uiswitch(app.SpectrophometerPanel, 'slider');
            app.StartAcquisitionSwitch.ValueChangedFcn = createCallbackFcn(app, @StartAcquisitionSwitchValueChanged, true);
            app.StatusLampLabel = uilabel(app.SpectrophometerPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.SpectrophometerPanel);
            app.AcquisitionIntervalsLabel = uilabel(app.SpectrophometerPanel);
            app.AcquisitionIntervalsLabel.Text = 'Acquisition Interval (s)';
            app.AcquisitionIntervalEdit = uieditfield(app.SpectrophometerPanel, 'numeric');
            app.AcquisitionIntervalEdit.ValueChangedFcn = createCallbackFcn(app, @AcquisitionIntervalEditValueChanged, true);
            app.AcquisitionIntervalEdit.Value = 0.2;
            app.PumpsPanel = uipanel(app.SMARTTestBedUIFigure);
            app.PumpsPanel.Title = 'Pumps';
            app.AxesConc = uiaxes(app.PumpsPanel);
            title(app.AxesConc, 'Real Time Concentration')
            xlabel(app.AxesConc, 'Time (min)')
            ylabel(app.AxesConc, 'C/C_p ')
            app.AxesConc.YLim = [-0.1 2];
            app.Pump1CheckBox = uicheckbox(app.PumpsPanel);
            app.Pump1CheckBox.Text = 'Pump 1';
            app.COMEditFieldLabel = uilabel(app.PumpsPanel);
            app.COMEditFieldLabel.Text = 'COM:';
            app.COMPump1Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.COMPump1Edit.Value = 5;
            app.SteadyLabel = uilabel(app.PumpsPanel);
            app.SteadyLabel.Text = 'Steady';
            app.SteadyPump1Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.SteadyPump1Edit.Value = 1.5;
            app.MaxLabel = uilabel(app.PumpsPanel);
            app.MaxLabel.Enable = 'off';
            app.MaxLabel.Text = 'Max';
            app.MaxPump1Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.MaxPump1Edit.Enable = 'off';
            app.Pump2CheckBox = uicheckbox(app.PumpsPanel);
            app.Pump2CheckBox.Text = 'Pump 2';
            app.COMEditFieldLabel_2 = uilabel(app.PumpsPanel);
            app.COMEditFieldLabel_2.Text = 'COM:';
            app.COMPump2Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.COMPump2Edit.Value = 6;
            app.SteadyLabel_2 = uilabel(app.PumpsPanel);
            app.SteadyLabel_2.Text = 'Steady';
            app.SteadyPump2Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.SteadyPump2Edit.Value = 0.5;
            app.MaxLabel_2 = uilabel(app.PumpsPanel);
            app.MaxLabel_2.Enable = 'off';
            app.MaxLabel_2.Text = 'Max';
            app.MaxPump2Edit = uieditfield(app.PumpsPanel, 'numeric');
            app.MaxPump2Edit.Enable = 'off';
            app.SMARTSwitchLabel = uilabel(app.PumpsPanel);
            app.SMARTSwitchLabel.Text = 'SMART';
            app.SMARTSwitch = uiswitch(app.PumpsPanel, 'toggle');
            app.WtSliderLabel = uilabel(app.PumpsPanel);
            app.WtSliderLabel.Enable = 'off';
            app.WtSliderLabel.Text = 'Wt';
            app.WtSlider = uislider(app.PumpsPanel);
            app.WtSlider.Limits = [0 1];
            app.WtSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.WtSlider.Enable = 'off';
            app.Pump1GaugeLabel = uilabel(app.PumpsPanel);
            app.Pump1GaugeLabel.Enable = 'off';
            app.Pump1GaugeLabel.Text = 'Pump 1';
            app.Pump1Gauge = uigauge(app.PumpsPanel, 'semicircular');
            app.Pump1Gauge.Enable = 'off';
            app.Pump2GaugeLabel = uilabel(app.PumpsPanel);
            app.Pump2GaugeLabel.Enable = 'off';
            app.Pump2GaugeLabel.Text = 'Pump 2';
            app.Pump2Gauge = uigauge(app.PumpsPanel, 'semicircular');
            app.Pump2Gauge.Enable = 'off';
            app.Pump3GaugeLabel = uilabel(app.PumpsPanel);
            app.Pump3GaugeLabel.Enable = 'off';
            app.Pump3GaugeLabel.Text = 'Pump 3';
            app.Pump3Gauge = uigauge(app.PumpsPanel, 'semicircular');
            app.Pump3Gauge.Enable = 'off';
            app.SteadyPump1mlhr = uilabel(app.PumpsPanel);
            app.SteadyPump1mlhr.Text = 'ml/hr';
            app.SteadyPump2mlhr = uilabel(app.PumpsPanel);
            app.SteadyPump2mlhr.Text = 'ml/hr';
            app.MaxPump1mlhr = uilabel(app.PumpsPanel);
            app.MaxPump1mlhr.Enable = 'off';
            app.MaxPump1mlhr.Text = 'ml/hr';
            app.MaxPump2mlhr = uilabel(app.PumpsPanel);
            app.MaxPump2mlhr.Enable = 'off';
            app.MaxPump2mlhr.Text = 'ml/hr';
            app.SMARTTestBedLabel = uilabel(app.SMARTTestBedUIFigure);
            app.SMARTTestBedLabel.Text = 'SMART Test Bed';
            app.ExperimentStepsLabel = uilabel(app.SMARTTestBedUIFigure);
            app.ExperimentStepsLabel.Text = 'Experiment Steps';
            app.DrugFlushButton = uibutton(app.SMARTTestBedUIFigure, 'push');
            app.DrugFlushButton.ButtonPushedFcn = createCallbackFcn(app, @DrugFlushButtonPushed, true);
            app.DrugFlushButton.Enable = 'off';
            app.DrugFlushButton.Text = 'Drug Flush';
            app.SalineFlushButton = uibutton(app.SMARTTestBedUIFigure, 'push');
            app.SalineFlushButton.ButtonPushedFcn = createCallbackFcn(app, @SalineFlushButtonPushed, true);
            app.SalineFlushButton.Enable = 'off';
            app.SalineFlushButton.Text = 'Saline Flush';
            app.BeginExperimentButton = uibutton(app.SMARTTestBedUIFigure, 'push');
            app.BeginExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @BeginExperimentButtonPushed, true);
            app.BeginExperimentButton.Enable = 'off';
            app.BeginExperimentButton.Text = 'Begin Experiment';
            app.EndExperimentButton = uibutton(app.SMARTTestBedUIFigure, 'push');
            app.EndExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @EndExperimentButtonPushed, true);
            app.EndExperimentButton.Enable = 'off';
            app.EndExperimentButton.Text = 'End Experiment';
            app.DataLoggerLabel = uilabel(app.SMARTTestBedUIFigure);
            app.DataLoggerLabel.Text = 'Data Logger';
            app.DataLoggerSwitch = uiswitch(app.SMARTTestBedUIFigure, 'slider');
            app.DataLoggerSwitch.ValueChangedFcn = createCallbackFcn(app, @DataLoggerSwitchValueChanged, true);
            app.DataLoggerLamp = uilamp(app.SMARTTestBedUIFigure);
            app.FileEditFieldLabel = uilabel(app.SMARTTestBedUIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.SMARTTestBedUIFigure, 'text');
            app.FileEditField.Editable = 'off';
            app.FileLoadButton = uibutton(app.SMARTTestBedUIFigure, 'push');
            app.FileLoadButton.ButtonPushedFcn = createCallbackFcn(app, @FileLoadButtonPushed, true);
            app.FileLoadButton.Text = '..';
            % Show the figure after all components are created
            app.SMARTTestBedUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmartTestBed

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SMARTTestBedUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SMARTTestBedUIFigure)
        end
    end
end